classdef tiffver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SimulationTab               matlab.ui.container.Tab
        Panel_12                    matlab.ui.container.Panel
        ThreatMitigationPanelLabel  matlab.ui.control.Label
        UITable2                    matlab.ui.control.Table
        Panel_9                     matlab.ui.container.Panel
        success                     matlab.ui.control.Image
        otpr6                       matlab.ui.control.Button
        error1                      matlab.ui.control.Image
        error2                      matlab.ui.control.Image
        otpr1                       matlab.ui.control.Button
        otpr2                       matlab.ui.control.Button
        otpr3                       matlab.ui.control.Button
        otpr4                       matlab.ui.control.Button
        otpr8                       matlab.ui.control.Button
        otpr7                       matlab.ui.control.Button
        otpr5                       matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        Image_2                     matlab.ui.control.Image
        Panel_11                    matlab.ui.container.Panel
        UIAxes2                     matlab.ui.control.UIAxes
        Panel_10                    matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        Panel_7                     matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        EditField_5                 matlab.ui.control.EditField
        EditField_4                 matlab.ui.control.EditField
        EditField_3                 matlab.ui.control.EditField
        EditField_2                 matlab.ui.control.EditField
        EditField                   matlab.ui.control.EditField
        Panel_6                     matlab.ui.container.Panel
        ThreatDetectionPanelLabel   matlab.ui.control.Label
        AccessBTSLabel              matlab.ui.control.Label
        Panel_5                     matlab.ui.container.Panel
        SimulationControlsLabel     matlab.ui.control.Label
        Panel_4                     matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        RunTimeLabel                matlab.ui.control.Label
        ResetSimulationButton       matlab.ui.control.Button
        StartSimulationButton       matlab.ui.control.Button
        GridLayout4                 matlab.ui.container.GridLayout
        Lamp_4                      matlab.ui.control.Lamp
        Lamp_3                      matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
        LegitimateAccessLabel       matlab.ui.control.Label
        IllegalAccessLabel          matlab.ui.control.Label
        EstimatingLabel             matlab.ui.control.Label
        Panel_3                     matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        Lamp                        matlab.ui.control.Lamp
        TVWSOperatorButton          matlab.ui.control.Button
        Panel_2                     matlab.ui.container.Panel
        LogsTab                     matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        UIAxes3_4                   matlab.ui.control.UIAxes
        UIAxes3_3                   matlab.ui.control.UIAxes
        UIAxes3_2                   matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        Tab                         matlab.ui.container.Tab
        ResetTVWSOpeatorButton      matlab.ui.control.Button
    end

    
    %properties (Access = private)
     %   Property % Description
    %end

    %properties (Access = private)
     %   situationIndex = 0;       % Tracks current situation (1 to 5)
      %  situationResults = [];    % Stores the 5 randomized red/green outcomes
       % simulationActive = false; % Tracks whether simulation is started
    %end

    properties (Access = private)
        situationIndex = 0;       % Tracks current situation (1 to 5)
        situationResults = [];    % Stores 5 randomized red/green outcomes
        simulationActive = false; % Whether simulation started
        readyForNext = false;     % Whether ready for next situation
        allDone = false;          % True when all 5 situations are done
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            % Prevent restarting if already running
            if app.simulationActive
                uialert(app.UIFigure, 'Simulation is already running.', 'Notice');
                return;
            end
        
            % Initialize simulation
            app.simulationActive = true;
            app.situationIndex = 0;
            app.readyForNext = true;
            app.allDone = false;
        
            % Randomize 4 reds (0) and 1 green (1)
            results = [zeros(1,4), 1];
            app.situationResults = results(randperm(5));

            app.Lamp.Color = [1 0.65 0];
            app.otpr1.Visible = 'on'; pause(1);
             % Lamp orange — ready to start
            app.otpr2.Visible = 'on'; pause(1);
         
           
        end

        % Button pushed function: TVWSOperatorButton
        function TVWSOperatorButtonPushed(app, event)


    % Must start first
    if ~app.simulationActive
        uialert(app.UIFigure, 'Please start the simulation first.', 'Notice');
        return;
    end

    % --- If all 5 situations were already done ---
    %if app.allDone
        % If user clicks AGAIN after 5th situation → start 2nd loop
     %   app.allDone = false;
      %  app.situationIndex = 0;
       % uialert(app.UIFigure, ...
        %    'Recheck started. Running the second loop of situations.', ...
         %   'Recheck Mode');
    %end

    % --- Increment current situation ---
    app.situationIndex = app.situationIndex + 1;

    % --- Stop if exceeding 5 ---
    if app.situationIndex > 5
        app.allDone = true;
        uialert(app.UIFigure, ...
            'All possible situations have been displayed. Click again to recheck all threats.', ...
            'Simulation Complete');
        return;
    end

    % --- Update Run Time (1–5) ---
    app.RunTimeLabel.Text = sprintf('Run Time: %d', app.situationIndex);

    % --- Turn OFF all previous outputs ---
    app.otpr1.Visible = 'off';
    app.otpr2.Visible = 'off';
    app.otpr3.Visible = 'off';
    app.otpr4.Visible = 'off';
    app.otpr5.Visible = 'off';
    app.otpr6.Visible = 'off';
    app.otpr7.Visible = 'off';
    app.otpr8.Visible = 'off';
    app.error1.Visible = 'off';
    app.error2.Visible = 'off';
    app.success.Visible = 'off';

    pause(0.3); % small delay for realism

    % --- Display based on results ---
    if app.situationResults(app.situationIndex) == 1
        app.Lamp.Color = [1 0.65 0]; % Orange
        % ✅ GREEN
        app.error1.Visible = 'off';
        app.error2.Visible = 'off';
        app.otpr1.Visible = 'on'; 
        app.otpr2.Visible = 'on';
        app.otpr3.Visible = 'on'; pause(1);
        app.otpr4.Visible = 'on'; 
        app.otpr5.Visible = 'on'; pause(1);
        app.otpr6.Visible = 'on';
        app.otpr7.Visible = 'on'; pause(1);
        app.success.Visible = 'on';
        app.Lamp.Color = [0 1 0];
    else
        % 🔴 RED
        if app.situationIndex <= 5
            app.Lamp.Color = [1 0.65 0]; % Orange
            app.otpr1.Visible = 'on'; pause(1);
            app.otpr2.Visible = 'on';
            app.otpr3.Visible = 'on'; pause(1);
            app.error1.Visible = 'on'; pause(1);
            %warndlg('Threat Detected.', 'Notice');
            uialert(app.UIFigure, 'Threat Detected', 'Notice');
        else
            app.error2.Visible = 'on';
        end
        app.Lamp.Color = [1 0 0];
    end


        end

        % Button pushed function: ResetTVWSOpeatorButton
        function ResetTVWSOpeatorButtonPushed(app, event)
            % Must start first
            if ~app.simulationActive
                uialert(app.UIFigure, 'Please start the simulation first.', 'Notice');
                return;
            end

            app.success.Visible = 'off';  
            app.otpr7.Visible = 'off'; 
            app.otpr6.Visible = 'off'; pause(1);
            app.otpr5.Visible = 'off'; 
            app.otpr4.Visible = 'off';
            app.error1.Visible = 'off'; 
            app.error2.Visible = 'off'; 
            app.otpr3.Visible = 'off'; pause(1);
            app.otpr2.Visible = 'off'; 
            app.otpr1.Visible = 'off';
        
            % --- CASE 1: If all 5 done (recheck mode) ---
            if app.allDone
                % Go to next situation automatically (no orange)
                app.situationIndex = app.situationIndex + 1;
        
                % If reach beyond 5, restart recheck loop
                if app.situationIndex > 5
                    app.situationIndex = 1;
                end
        
                % Show same results directly
                if app.situationResults(app.situationIndex) == 1
                    app.Lamp.Color = [0 1 0]; % Green
                else
                    app.Lamp.Color = [1 0 0]; % Red
                end
        
                % Keep ready for next recheck
                app.readyForNext = true;
                return;
            end
        
            % --- CASE 2: Normal simulation (first loop) ---
            app.readyForNext = true;
            app.Lamp.Color = [1 0.65 0]; % Orange
        end

        % Button pushed function: ResetSimulationButton
        function ResetSimulationButtonPushed(app, event)
            app.success.Visible = 'off';  
            app.otpr7.Visible = 'off'; 
            app.otpr6.Visible = 'off'; pause(1);
            app.otpr5.Visible = 'off'; 
            app.otpr4.Visible = 'off';
            app.error1.Visible = 'off'; 
            app.error2.Visible = 'off'; 
            app.otpr3.Visible = 'off'; pause(1);
            app.otpr2.Visible = 'off'; 
            app.otpr1.Visible = 'off';
            
            % Full reset
            app.situationIndex = 0;
            app.simulationActive = false;
            app.readyForNext = false;
            app.allDone = false;
            app.situationResults = [];
        
            % Reset lamp to gray
            app.Lamp.Color = [0.5 0.5 0.5];
            app.RunTimeLabel.Text = 'Run Time: 0';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.Panel_4 = uipanel(app.SimulationTab);
            app.GridLayout = uigridlayout(app.Panel_4);
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_3 = uipanel(app.GridLayout);
            app.Panel_3.Title = '.';
            app.GridLayout3 = uigridlayout(app.Panel_3);
            app.TVWSOperatorButton = uibutton(app.GridLayout3, 'push');
            app.TVWSOperatorButton.ButtonPushedFcn = createCallbackFcn(app, @TVWSOperatorButtonPushed, true);
            app.TVWSOperatorButton.Text = 'TVWS Operator';
            app.Lamp = uilamp(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.EstimatingLabel = uilabel(app.GridLayout4);
            app.EstimatingLabel.Text = 'Estimating';
            app.IllegalAccessLabel = uilabel(app.GridLayout4);
            app.IllegalAccessLabel.Text = 'Illegal Access';
            app.LegitimateAccessLabel = uilabel(app.GridLayout4);
            app.LegitimateAccessLabel.Text = 'Legitimate Access';
            app.Lamp_2 = uilamp(app.GridLayout4);
            app.Lamp_3 = uilamp(app.GridLayout4);
            app.Lamp_4 = uilamp(app.GridLayout4);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.StartSimulationButton = uibutton(app.GridLayout2, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.ResetSimulationButton = uibutton(app.GridLayout2, 'push');
            app.ResetSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSimulationButtonPushed, true);
            app.ResetSimulationButton.Text = 'Reset Simulation';
            app.RunTimeLabel = uilabel(app.GridLayout2);
            app.RunTimeLabel.Text = 'Run Time: 0';
            app.Panel_5 = uipanel(app.SimulationTab);
            app.SimulationControlsLabel = uilabel(app.Panel_5);
            app.SimulationControlsLabel.Text = 'Simulation Controls';
            app.AccessBTSLabel = uilabel(app.SimulationTab);
            app.AccessBTSLabel.Text = 'Access BTS';
            app.Panel_6 = uipanel(app.SimulationTab);
            app.ThreatDetectionPanelLabel = uilabel(app.Panel_6);
            app.ThreatDetectionPanelLabel.Text = 'Threat Detection Panel';
            app.Panel_7 = uipanel(app.SimulationTab);
            app.GridLayout5 = uigridlayout(app.Panel_7);
            app.EditField = uieditfield(app.GridLayout5, 'text');
            app.EditField_2 = uieditfield(app.GridLayout5, 'text');
            app.EditField_3 = uieditfield(app.GridLayout5, 'text');
            app.EditField_4 = uieditfield(app.GridLayout5, 'text');
            app.EditField_5 = uieditfield(app.GridLayout5, 'text');
            app.Panel_10 = uipanel(app.SimulationTab);
            app.UIAxes = uiaxes(app.Panel_10);
            title(app.UIAxes, {'Recieve Signal '; 'Characterization'})
            zlabel(app.UIAxes, 'Z')
            app.Panel_11 = uipanel(app.SimulationTab);
            app.UIAxes2 = uiaxes(app.Panel_11);
            title(app.UIAxes2, 'Figure 1')
            xlabel(app.UIAxes2, 'avg. SNR per receive antenna [dB]')
            ylabel(app.UIAxes2, 'uncoded bit error-rate (BER)')
            zlabel(app.UIAxes2, 'Z')
            app.Panel_9 = uipanel(app.SimulationTab);
            app.Image_2 = uiimage(app.Panel_9);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Images', 'towertowertower.png');
            app.Image = uiimage(app.Panel_9);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'bts.png');
            app.otpr5 = uibutton(app.Panel_9, 'push');
            app.otpr5.Visible = 'off';
            app.otpr7 = uibutton(app.Panel_9, 'push');
            app.otpr7.Visible = 'off';
            app.otpr8 = uibutton(app.Panel_9, 'push');
            app.otpr8.Visible = 'off';
            app.otpr4 = uibutton(app.Panel_9, 'push');
            app.otpr4.Visible = 'off';
            app.otpr3 = uibutton(app.Panel_9, 'push');
            app.otpr3.Visible = 'off';
            app.otpr2 = uibutton(app.Panel_9, 'push');
            app.otpr2.Visible = 'off';
            app.otpr1 = uibutton(app.Panel_9, 'push');
            app.otpr1.Visible = 'off';
            app.error2 = uiimage(app.Panel_9);
            app.error2.Visible = 'off';
            app.error2.ImageSource = fullfile(pathToMLAPP, 'Images', 'remove-removebg-preview.png');
            app.error1 = uiimage(app.Panel_9);
            app.error1.Visible = 'off';
            app.error1.ImageSource = fullfile(pathToMLAPP, 'Images', 'remove-removebg-preview.png');
            app.otpr6 = uibutton(app.Panel_9, 'push');
            app.otpr6.Visible = 'off';
            app.success = uiimage(app.Panel_9);
            app.success.Visible = 'off';
            app.success.ImageSource = fullfile(pathToMLAPP, 'Images', 'check.png');
            app.UITable2 = uitable(app.SimulationTab);
            app.UITable2.ColumnName = {'Operator Number'; 'Type of Violator'};
            app.UITable2.RowName = {};
            app.Panel_12 = uipanel(app.SimulationTab);
            app.ThreatMitigationPanelLabel = uilabel(app.Panel_12);
            app.ThreatMitigationPanelLabel.Text = 'Threat Mitigation Panel';
            app.LogsTab = uitab(app.TabGroup);
            app.LogsTab.Title = 'Logs';
            app.UIAxes3 = uiaxes(app.LogsTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.LogsTab);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_3 = uiaxes(app.LogsTab);
            title(app.UIAxes3_3, 'Title')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes3_4 = uiaxes(app.LogsTab);
            title(app.UIAxes3_4, 'Title')
            xlabel(app.UIAxes3_4, 'X')
            ylabel(app.UIAxes3_4, 'Y')
            zlabel(app.UIAxes3_4, 'Z')
            app.UITable = uitable(app.LogsTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '.';
            app.ResetTVWSOpeatorButton = uibutton(app.Tab, 'push');
            app.ResetTVWSOpeatorButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTVWSOpeatorButtonPushed, true);
            app.ResetTVWSOpeatorButton.Text = 'Reset TVWS Opeator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tiffver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end