classdef tiffverSISO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        SimulationTab                matlab.ui.container.Tab
        Panel_12                     matlab.ui.container.Panel
        ThreatMitigationPanelLabel   matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        Panel_11                     matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        Panel_9                      matlab.ui.container.Panel
        TVWSOperatorLabel            matlab.ui.control.Label
        BaseTranscieverStationLabel  matlab.ui.control.Label
        success                      matlab.ui.control.Image
        otpr6                        matlab.ui.control.Button
        error1                       matlab.ui.control.Image
        error2                       matlab.ui.control.Image
        otpr1                        matlab.ui.control.Button
        otpr2                        matlab.ui.control.Button
        otpr3                        matlab.ui.control.Button
        otpr4                        matlab.ui.control.Button
        otpr8                        matlab.ui.control.Button
        otpr7                        matlab.ui.control.Button
        otpr5                        matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        Image_2                      matlab.ui.control.Image
        Panel_10                     matlab.ui.container.Panel
        UIAxes_2                     matlab.ui.control.UIAxes
        Panel_7                      matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        EditField_5                  matlab.ui.control.EditField
        EditField_4                  matlab.ui.control.EditField
        EditField_3                  matlab.ui.control.EditField
        EditField_2                  matlab.ui.control.EditField
        EditField_1                  matlab.ui.control.EditField
        Panel_6                      matlab.ui.container.Panel
        ThreatDetectionPanelLabel    matlab.ui.control.Label
        AccessBTSLabel               matlab.ui.control.Label
        Panel_5                      matlab.ui.container.Panel
        SimulationControlsLabel      matlab.ui.control.Label
        Panel_4                      matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        RunTimeLabel                 matlab.ui.control.Label
        ResetSimulationButton        matlab.ui.control.Button
        StartSimulationButton        matlab.ui.control.Button
        GridLayout4                  matlab.ui.container.GridLayout
        Lamp_4                       matlab.ui.control.Lamp
        Lamp_3                       matlab.ui.control.Lamp
        Lamp_2                       matlab.ui.control.Lamp
        LegitimateAccessLabel        matlab.ui.control.Label
        IllegalAccessLabel           matlab.ui.control.Label
        EstimatingLabel              matlab.ui.control.Label
        Panel_3                      matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        Lamp                         matlab.ui.control.Lamp
        TVWSOperatorButton           matlab.ui.control.Button
        Panel_2                      matlab.ui.container.Panel
        LogsTab                      matlab.ui.container.Tab
        UITable1                     matlab.ui.control.Table
        UIAxes3_4                    matlab.ui.control.UIAxes
        UIAxes3_3                    matlab.ui.control.UIAxes
        UIAxes3_2                    matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        Tab                          matlab.ui.container.Tab
        ResetTVWSOpeatorButton       matlab.ui.control.Button
    end

    
     properties (Access = private)
        %situationIndex = 0;
        %situations cell = {}      
        %simulationActive = false; 
        
        situationIndex double = 0 % Counter for current run, Tracks current situation (1 to 5)
        situationResults = [];    % Stores 5 randomized red/green outcomes
        simulationActive logical = false    % Whether simulation is active or has started
        readyForNext = false;     % Whether ready for next situation
        allDone = false;          % True when all 5 situations are done
        randomOrder double = []   % Randomized order of 1–5 situations
        situations cell = {       % Predefined situations, Table of possible situations
            'OFDM-compliant', ...
            'Jammerless', ...
            'Cyclic prefix-violating', ...
            'OFDM-noncompliant', ...
            'None' % Legitimate
        }
     end

     methods (Access = private)
        %monte carlo funcions
        function [par, res] = runMonteCarlo(app)

            par.B = 8;                    % number of receive antennas
            par.U = 2;                    % number of transmit antennas and data streams
            par.L = 4;                    % number of channel taps
            par.P = 16;                   % cyclic prefix length
            par.N_sc = 64;                % number of total subcarriers
            par.tonelist = [-26:-22, -20:-8, -6:-1, 1:6, 8:20, 22:26] + 32; % list of used subcarriers, only meaningful for par.N_sc = 64;
            par.num_tones = length(par.tonelist);
            par.num_ofdm_symbols = 50;    % number of OFDM symbols sent per channel realization
            par.rho_dB = 30;              % jammer strength relative to signal strength (in dB)
            par.mod = 'QPSK';             % transmit constellation
            par.random_jammer_power = 0;  % if true, then the jammer-power is uniformly (in dB) sampled from 
                                          % the range [0, par.rho_dB] for every trial. 
                                          % Otherwise, it is deterministically equal par.rho_dB.
            
            par.trials = 100;             % number of Monte-Carlo trials (different channel realizations)
            par.SNRdB_list = -5:1:20;     % list of SNR values (in dB) to be simulated
            
            par.plot = 1;                 % whether or not to plot the results
            par.printmsg = 1;             % whether or not to print log messages
            
            rng(0);                       % random seed
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% simulation start
            %%%%%%%%%%%%%%%%%%%%%%%%%%%
          
            %%%%%%%%%%%%%%%%%%%%%%%%%%%
%%% configurable parameters
%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Corresponds to Section II (System Model) of the paper: Defines the SISO-OFDM system parameters,
% including antenna counts, channel taps, modulation, and simulation settings for evaluating jammer impact.
num_receive_antennas = 1;                    % 8 par.B:BTS  number of receive antennas
num_transmit_antennas = 1;                    % 2 par.U:OPERATOR number of transmit antennas and data streams
num_channel_taps = 2;                        % 4 par.L: number of channel taps (multipath delay spread)
cyclic_prefix_length = 16;                   % par.P: cyclic prefix length (to combat inter-symbol interference)
num_subcarriers = 64;                        % par.N_sc: number of total subcarriers (FFT size for OFDM)
used_subcarrier_indices = [-26:-22, -20:-8, -6:-1, 1:6, 8:20, 22:26] + 32; % par.tonelist: list of used subcarriers (data-bearing tones), shifted by 32 for DC centering
num_used_subcarriers = length(used_subcarrier_indices); % par.num_tones: total number of active subcarriers
num_ofdm_symbols = 50;                       % par.num_ofdm_symbols: number of OFDM symbols sent per channel realization (temporal diversity)
jammer_power_dB = 30;                        % par.rho_dB: jammer strength relative to signal strength (in dB) - high value simulates strong jamming
modulation_scheme = 'BPSK';                  % par.mod: transmit constellation (modulation scheme: QPSK for 2 bits/symbol)
random_jammer_power_flag = 0;                % par.random_jammer_power: if true, jammer power varies randomly per trial; else fixed at par.rho_dB
    
num_trials = 100;                            % par.trials: number of Monte-Carlo trials (different channel realizations for statistical averaging)
snr_dB_list = -5:1:20;                       % par.SNRdB_list: list of SNR values (in dB) to simulate (signal-to-noise ratio sweep)

plot_flag = 1;                              % par.plot: flag to enable/disable plotting of simulation results
print_messages_flag = 1;                     % par.printmsg: flag to enable/disable printing of progress messages during simulation

rng(0);                                     % set random seed for reproducibility of results

%% 

%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%% simulation start
%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Corresponds to Section II (System Model): Modulation constellation definition and bit mapping for signal generation.

% create a unique simulation name based on parameters for logging or saving results
%summarzes the given paramenters
simulation_name = [num2str(num_receive_antennas) 'x' num2str(num_transmit_antennas) '_' modulation_scheme '_L', num2str(num_channel_taps) '_P' num2str(cyclic_prefix_length) ...
  '_Nsc' num2str(num_subcarriers) '_Nofd' num2str(num_ofdm_symbols) '_rho' num2str(jammer_power_dB) ...
  '_' num2str(num_trials) 'Trials']; % par.simName
fprintf(simulation_name)

% define the modulation constellation based on the chosen modulation scheme
switch (modulation_scheme)
  case 'BPSK'  % Binary Phase Shift Keying: 2 symbols, 1 bit each
    constellation_symbols = [ -1 1 ]; % par.symbols
  case 'QPSK'  % Quadrature Phase Shift Keying: 4 symbols, 2 bits each
    constellation_symbols = [ ... % par.symbols
      -1-1i,-1+1i, ...
      +1-1i,+1+1i ];
  case '16QAM'  % 16-Quadrature Amplitude Modulation: 16 symbols, 4 bits each
    constellation_symbols = [ ... % par.symbols
      -3-3i,-3-1i,-3+3i,-3+1i, ...
      -1-3i,-1-1i,-1+3i,-1+1i, ...
      +3-3i,+3-1i,+3+3i,+3+1i, ...
      +1-3i,+1-1i,+1+3i,+1+1i ];
  case '64QAM'  % 64-Quadrature Amplitude Modulation: 64 symbols, 6 bits each
    constellation_symbols = [ ... % par.symbols
      -7-7i,-7-5i,-7-1i,-7-3i,-7+7i,-7+5i,-7+1i,-7+3i, ...
      -5-7i,-5-5i,-5-1i,-5-3i,-5+7i,-5+5i,-5+1i,-5+3i, ...
      -1-7i,-1-5i,-1-1i,-1-3i,-1+7i,-1+5i,-1+1i,-1+3i, ...
      -3-7i,-3-5i,-3-1i,-3-3i,-3+7i,-3+5i,-3+1i,-3+3i, ...
      +7-7i,+7-5i,+7-1i,+7-3i,+7+7i,+7+5i,+7+1i,+7+3i, ...
      +5-7i,+5-5i,+5-1i,+5-3i,+5+7i,+5+5i,+5+1i,+5+3i, ...
      +1-7i,+1-5i,+1-1i,+1-3i,+1+7i,+1+5i,+1+1i,+1+3i, ...
      +3-7i,+3-5i,+3-1i,+3-3i,+3+7i,+3+5i,+3+1i,+3+3i ];
end

% normalize the constellation to have unit average energy for fair comparison
constellation_energy = mean(abs(constellation_symbols).^2);  % par.constellation_energy: compute average symbol energy
constellation_symbols = constellation_symbols / sqrt(constellation_energy);  % normalize symbols
normalized_energy = mean(abs(constellation_symbols).^2);  % par.Es: verify normalized energy is 1

% precompute bit-to-symbol mapping for efficient decoding
bits_per_symbol = log2(length(constellation_symbols));  % par.Q: bits per symbol (e.g., 2 for QPSK)
bit_mappings = de2bi(0:length(constellation_symbols)-1, bits_per_symbol, 'left-msb');  % par.bits: binary representations of symbol indices

% track simulation time
time_elapsed = 0;

% intermediate results
y_jammerless_frequency = zeros(num_receive_antennas,num_subcarriers,num_ofdm_symbols); % y_jl_f
y_jammer_compliant_frequency = zeros(num_receive_antennas,num_subcarriers,num_ofdm_symbols); % y_oj_comp_f
y_jammer_noncompliant_frequency = zeros(num_receive_antennas,num_subcarriers,num_ofdm_symbols); % y_oj_noncomp_f
y_compliant_frequency = zeros(num_receive_antennas,num_subcarriers,num_ofdm_symbols); % y_comp_f
y_noncompliant_frequency = zeros(num_receive_antennas,num_subcarriers,num_ofdm_symbols); % y_noncomp_f


%% 

% results
jammer_compliant_freq_domain = zeros(num_trials, num_used_subcarriers,num_receive_antennas, num_ofdm_symbols); % Y_oj_comp
jammer_noncompliant_freq_domain = zeros(num_trials, num_used_subcarriers,num_receive_antennas, num_ofdm_symbols); % Y_oj_noncomp

mse_jammerless = zeros(1, length(snr_dB_list)); % MSE_jl
mse_compliant = zeros(1, length(snr_dB_list)); % MSE_comp
mse_noncompliant = zeros(1, length(snr_dB_list)); % MSE_noncomp

results.BER_jammerless = zeros(1, length(snr_dB_list)); % res.BER_jl
results.BER_compliant = zeros(1, length(snr_dB_list)); % res.BER_comp
results.BER_noncompliant = zeros(1, length(snr_dB_list)); % res.BER_noncomp
results.BER_projections = zeros(num_channel_taps, length(snr_dB_list)); % res.BER_projs

results.spatial_interference_distribution = zeros(num_receive_antennas,num_used_subcarriers);

% Initialize variables for per-trial errors to compute success rate of mitigating illegal users/channel taps
errors_jammerless_per_trial = zeros(num_trials, length(snr_dB_list)); % errors_jl_per_trial
errors_compliant_per_trial = zeros(num_trials, length(snr_dB_list)); % errors_comp_per_trial
errors_noncompliant_per_trial = zeros(num_trials, length(snr_dB_list)); % errors_noncomp_per_trial

% generate bit stream
bits = randi([0 1],num_trials,num_ofdm_symbols,num_transmit_antennas,bits_per_symbol,num_used_subcarriers); % bits
indices = zeros(num_trials, num_ofdm_symbols, num_transmit_antennas,num_used_subcarriers); % idx

%% 

% trials loop
% Corresponds to Section III (Jammer Modeling and Interference Analysis): Monte-Carlo simulation loop for channel realizations and jammer signal generation.
tic
for trial_index=1:num_trials

  % generate jammer's channel in the time domain
  % Corresponds to Section II (System Model): Rayleigh fading channel generation.
  jammer_channel_time = sqrt(0.5)*randn(num_receive_antennas,num_channel_taps) + 1j*sqrt(0.5)*randn(num_receive_antennas,num_channel_taps);  % j_
  jammer_channel_freq = (1/sqrt(num_subcarriers))*fft(jammer_channel_time,num_subcarriers,2);  % j_f

  % generate the UEs' channel in the time domain
  user_channel_time = sqrt(0.5)*randn(num_receive_antennas,num_transmit_antennas,num_channel_taps) + 1j*sqrt(0.5)*randn(num_receive_antennas,num_transmit_antennas,num_channel_taps);  % H_
  user_channel_freq = (1/sqrt(num_subcarriers))*fft(user_channel_time,num_subcarriers,3); % H_f
  % E[||H_f(:,:,i)||_F^2] = B*U*L/N_sc for all i=1,...,N_sc
  % expected receive signal energy per subcarrier is E_s*B*U*L/N_sc = B*U*L/N_sc

  % set jammer power: either random per trial or fixed
  if random_jammer_power_flag
    jammer_power_linear = num_transmit_antennas*10^(jammer_power_dB*rand()/10);  % par.rho
  else
    jammer_power_linear = num_transmit_antennas*10^(jammer_power_dB/10);  % par.rho
  end


  % loop over SNR values to simulate different noise levels
  for snr_index = 1:length(snr_dB_list)
    
    noise_variance = num_channel_taps*num_transmit_antennas*10^(-snr_dB_list(snr_index)/10);  % par.N0
    
    % loop over OFDM symbols for temporal averaging
    for ofdm_symbol_index=1:num_ofdm_symbols
  
      %%% generate transmit signals
      % Corresponds to Section III (Jammer Modeling): Gener ation of compliant and noncompliant jammer signals, and UE signals.
        
      % generate jammer's transmit signal in frequency domain (OFDM-compliant)
      jammer_signal_freq = sqrt(0.5)*randn(1,num_subcarriers)+ 1j*sqrt(0.5)*randn(1,num_subcarriers);  % w_f
      jammer_signal_time_no_cp = sqrt(num_subcarriers)*ifft(jammer_signal_freq); % w_no_cp
      jammer_signal_time = [jammer_signal_time_no_cp(:,num_subcarriers-cyclic_prefix_length+1:num_subcarriers), jammer_signal_time_no_cp];  % w
      jammer_signal_time = sqrt(jammer_power_linear)*jammer_signal_time;  % scale jammer power
  
      % generate jammer's transmit signal in time domain (OFDM-noncompliant)
      jammer_signal_time_noncomp = sqrt(0.5)*randn(1,num_subcarriers+cyclic_prefix_length)+ 1j*sqrt(0.5)*randn(1,num_subcarriers+cyclic_prefix_length); % w_noncomp
      jammer_signal_time_noncomp = sqrt(jammer_power_linear)*jammer_signal_time_noncomp;  % scale jammer power       

      % OFDM-compliant TVWS operator: Generation of legitimate user signal in TVWS using OFDM
      % generate UEs' transmit signal in frequency domain (OFDM-compliant)
       for tone_index=1:num_used_subcarriers
            indices(trial_index,ofdm_symbol_index,:,tone_index) = bi2de(bits(trial_index,ofdm_symbol_index,:,:,tone_index),'left-msb')+1; % idx
       end
      symbols_used_tones = constellation_symbols(squeeze(indices(trial_index,ofdm_symbol_index,:,:))); % S_f_used_tones
      symbols_freq = zeros(num_transmit_antennas, num_subcarriers);  % S_f
      % Subcarrier assignment: Assign the modulated symbols to the designated used subcarriers in the frequency domain
      symbols_freq(:,used_subcarrier_indices) = symbols_used_tones; % assign symbols only to used subcarriers
      symbols_time_no_cp = sqrt(num_subcarriers)*ifft(symbols_freq,num_subcarriers,2);  % S_no_cp
      symbols_time = [symbols_time_no_cp(:,num_subcarriers-cyclic_prefix_length+1:num_subcarriers), symbols_time_no_cp];  % S

    
      %%% generate receive signals
      % Threat DB 
      receive_signal_legitimate_TVWSO = zeros(num_receive_antennas, num_subcarriers+cyclic_prefix_length+num_channel_taps-1);% y_jl 8 x 64+16+4-1
      receive_signal_jammer_compliant = zeros(num_receive_antennas, num_subcarriers+cyclic_prefix_length+num_channel_taps-1);     % y_oj_comp
      receive_signal_jammer_noncompliant = zeros(num_receive_antennas, num_subcarriers+cyclic_prefix_length+num_channel_taps-1);  % y_oj_noncomp
      receive_signal_compliant = zeros(num_receive_antennas, num_subcarriers+cyclic_prefix_length+num_channel_taps-1);        % y_comp
      receive_signal_noncompliant = zeros(num_receive_antennas, num_subcarriers+cyclic_prefix_length+num_channel_taps-1);     % y_noncomp


      % convolve channels with transmit signals to simulate received signals
      for receive_ant_index=1:num_receive_antennas
        for transmit_ant_index=1:num_transmit_antennas %1->2
          receive_signal_legitimate_TVWSO(receive_ant_index,:) = receive_signal_legitimate_TVWSO(receive_ant_index,:) + conv(squeeze(user_channel_time(receive_ant_index,transmit_ant_index,:)),symbols_time(transmit_ant_index,:));  % sum of convolutions for each UE 
                %receive_signal_legitimate_TVWSO(1=>8,83) = 
        end
        receive_signal_jammer_compliant(receive_ant_index,:) = conv(jammer_channel_time(receive_ant_index,:),jammer_signal_time);  % jammer convolution compliant
        receive_signal_jammer_noncompliant(receive_ant_index,:) = conv(jammer_channel_time(receive_ant_index,:),jammer_signal_time_noncomp);  % jammer convolution noncompliant
      end
      receive_signal_compliant = receive_signal_legitimate_TVWSO + receive_signal_jammer_compliant;  % total received signal with compliant jammer
      receive_signal_noncompliant = receive_signal_legitimate_TVWSO + receive_signal_jammer_noncompliant;  % total received signal with noncompliant jammer


      %%% generate noise in the time domain
      noise_time = sqrt(0.5)*randn(num_receive_antennas,num_subcarriers)+ 1j*sqrt(0.5)*randn(num_receive_antennas,num_subcarriers);  % N
      noise_time = sqrt(noise_variance)*noise_time;  % scale noise by noise variance

      %%% truncate signals in time-domain to remove cyclic prefix and channel reverberation
      receive_signal_legitimate_TVWSO_truncated = receive_signal_legitimate_TVWSO(:,cyclic_prefix_length+1:cyclic_prefix_length+num_subcarriers) + noise_time;  % jammerless signal + noise
      receive_signal_jammer_compliant_truncated = receive_signal_jammer_compliant(:,cyclic_prefix_length+1:cyclic_prefix_length+num_subcarriers); % jammer only, no noise
      receive_signal_jammer_noncompliant_truncated = receive_signal_jammer_noncompliant(:,cyclic_prefix_length+1:cyclic_prefix_length+num_subcarriers); % jammer only, no noise
      receive_signal_compliant_truncated = receive_signal_compliant(:,cyclic_prefix_length+1:cyclic_prefix_length+num_subcarriers) + noise_time;  % full signal + noise
      receive_signal_noncompliant_truncated = receive_signal_noncompliant(:,cyclic_prefix_length+1:cyclic_prefix_length+num_subcarriers) + noise_time;  % full signal + noise


      %%% convert to frequency domain
      y_jammerless_frequency(:,:,ofdm_symbol_index) = (1/sqrt(num_subcarriers))*fft(receive_signal_legitimate_TVWSO_truncated, num_subcarriers, 2);  % jammerless frequency domain
      y_jammer_compliant_frequency(:,:,ofdm_symbol_index) = (1/sqrt(num_subcarriers))*fft(receive_signal_jammer_compliant_truncated, num_subcarriers, 2);  % jammer compliant freq domain
      y_jammer_noncompliant_frequency(:,:,ofdm_symbol_index) = (1/sqrt(num_subcarriers))*fft(receive_signal_jammer_noncompliant_truncated, num_subcarriers, 2);  % jammer noncompliant freq domain
      y_compliant_frequency(:,:,ofdm_symbol_index) = (1/sqrt(num_subcarriers))*fft(receive_signal_compliant_truncated, num_subcarriers, 2);  % full compliant freq domain
      y_noncompliant_frequency(:,:,ofdm_symbol_index) = (1/sqrt(num_subcarriers))*fft(receive_signal_noncompliant_truncated, num_subcarriers, 2);  % full noncompliant freq domain


      %%% remove subcarriers that are not used
      y_jammerless_frequency_tones = y_jammerless_frequency(:,used_subcarrier_indices,:);  % select used subcarriers only
      y_jammer_compliant_frequency_tones = y_jammer_compliant_frequency(:,used_subcarrier_indices,:);
      y_jammer_noncompliant_frequency_tones = y_jammer_noncompliant_frequency(:,used_subcarrier_indices,:);
      y_compliant_frequency_tones = y_compliant_frequency(:,used_subcarrier_indices,:);
      y_noncompliant_frequency_tones = y_noncompliant_frequency(:,used_subcarrier_indices,:);


      if trial_index == 1 && snr_index == length(snr_dB_list) && ofdm_symbol_index == 1
          plot_signal_jammerless = y_jammerless_frequency_tones;
          plot_signal_compliant = y_jammer_compliant_frequency_tones;
          plot_signal_noncompliant = y_jammer_noncompliant_frequency_tones;
      end

      user_channel_frequency_tones = user_channel_freq(:,:,used_subcarrier_indices);  % channel frequency response for used subcarriers
    
    
      %%% collect jammer statistics for later analysis
      for tone_index=1:num_used_subcarriers
        jammer_compliant_freq_domain(trial_index,tone_index,:,ofdm_symbol_index) = y_jammer_compliant_frequency_tones(:,tone_index,ofdm_symbol_index);  % store jammer compliant freq domain
        jammer_noncompliant_freq_domain(trial_index,tone_index,:,ofdm_symbol_index) = y_jammer_noncompliant_frequency_tones(:,tone_index,ofdm_symbol_index);  % store jammer noncompliant freq domain   
      end

    end % iterate over OFDM symbols with same channel realization


    for tone_index=1:num_used_subcarriers
  
      %%% extract jammer statistics
      % Corresponds to Section IV (Mitigation): SVD for subspace estimation.
      [U_compliant,S_compliant,~] = svd(squeeze(jammer_compliant_freq_domain(trial_index,tone_index,:,:)));  % singular value decomposition of jammer compliant
      [U_noncompliant,S_noncompliant,~] = svd(squeeze(jammer_noncompliant_freq_domain(trial_index,tone_index,:,:)));  % SVD of jammer noncompliant
      singular_values = diag(S_noncompliant);
      dominant_sv = singular_values(1);  % largest singular value
      results.spatial_interference_distribution(:, tone_index) = results.spatial_interference_distribution(:, tone_index) + dominant_sv;

      % form best rank-(num_receive_antennas-1) projectors: those that remove most of the
      % jammer interference per subcarrier
      % Corresponds to Section IV (Mitigation): Nulling matrices.
      jammer_estimate_compliant = U_compliant(:,1);  % dominant jammer subspace vector compliant
      % Mitigation: Projection matrix to nullify the compliant jammer subspace, mitigating its effect on TVWS operator
      projection_matrix_compliant = eye(num_receive_antennas) - jammer_estimate_compliant*jammer_estimate_compliant';  % projection matrix to null jammer compliant
      if num_receive_antennas == 1
          projection_matrix_compliant = eye(num_receive_antennas);  % no nulling in SISO
      end
      jammer_estimate_noncompliant = U_noncompliant(:,1);  % dominant jammer subspace vector noncompliant
      projection_matrix_noncompliant = eye(num_receive_antennas) - jammer_estimate_noncompliant*jammer_estimate_noncompliant';  % projection matrix to null jammer noncompliant
      if num_receive_antennas == 1
          projection_matrix_noncompliant = eye(num_receive_antennas);  % no nulling in SISO
      end

      % projectors = zeros(num_channel_taps,num_receive_antennas,num_receive_antennas);  % initialize multiple projectors for different nulling dimensions
      % for b=1:num_channel_taps
      %   projectors(b,:,:) = eye(num_receive_antennas) - U_noncompliant(:,1:b)*U_noncompliant(:,1:b)';  % projector nulling b jammer subspace vectors
      % end
      projectors = repmat(eye(num_receive_antennas), [num_channel_taps, 1, 1]); %no-op projection in SISO
  
      %%% detect signals using zero-forcing (and, in some cases, jammer mitigation)
      % Corresponds to Section IV (Mitigation): Zero-forcing detection with/without nulling.
      if num_receive_antennas == 1 && num_transmit_antennas == 1
        % SISO case: use element-wise division to avoid ill-conditioned matrix warnings
        symbols_estimate_jammerless = squeeze(y_jammerless_frequency_tones(:,tone_index,:)) ./ (sqrt(num_subcarriers) * squeeze(user_channel_frequency_tones(:,:,tone_index)));
        symbols_estimate_jammerless = reshape(symbols_estimate_jammerless, [num_receive_antennas, num_transmit_antennas, num_ofdm_symbols]);

        symbols_estimate_compliant = projection_matrix_compliant * squeeze(y_compliant_frequency_tones(:,tone_index,:)) ./ (sqrt(num_subcarriers) * projection_matrix_compliant * squeeze(user_channel_frequency_tones(:,:,tone_index)));
        symbols_estimate_compliant = reshape(symbols_estimate_compliant, [num_receive_antennas, num_transmit_antennas, num_ofdm_symbols]);

        symbols_estimate_noncompliant = projection_matrix_noncompliant * squeeze(y_noncompliant_frequency_tones(:,tone_index,:)) ./ (sqrt(num_subcarriers) * projection_matrix_noncompliant * squeeze(user_channel_frequency_tones(:,:,tone_index)));
        symbols_estimate_noncompliant = reshape(symbols_estimate_noncompliant, [num_receive_antennas, num_transmit_antennas, num_ofdm_symbols]);

        symbols_estimate_projections = zeros(num_receive_antennas,num_transmit_antennas,num_ofdm_symbols);  % initialize estimates for multiple projectors
        for b=1:num_channel_taps
          symbols_estimate_projections(b,:,:) = squeeze(projectors(b,:,:)) * squeeze(y_noncompliant_frequency_tones(:,tone_index,:)) ./ (sqrt(num_subcarriers) * squeeze(projectors(b,:,:)) * squeeze(user_channel_frequency_tones(:,:,tone_index)));
        end
      else
        % MIMO case: use pseudo-inverse
        symbols_estimate_jammerless = pinv(sqrt(num_subcarriers)*(user_channel_frequency_tones(:,:,tone_index))) * squeeze(y_jammerless_frequency_tones(:,tone_index,:));  % zero-forcing estimate jammerless

        % Mitigated TVWS operator symbol estimation using projection matrix to suppress compliant jammer interference
        symbols_estimate_compliant = pinv(sqrt(num_subcarriers)*(projection_matrix_compliant*squeeze(user_channel_frequency_tones(:,:,tone_index)))) * (projection_matrix_compliant*squeeze(y_compliant_frequency_tones(:,tone_index,:)));  % zero-forcing with jammer mitigation compliant
        symbols_estimate_noncompliant = pinv(sqrt(num_subcarriers)*(projection_matrix_noncompliant*squeeze(user_channel_frequency_tones(:,:,tone_index)))) * (projection_matrix_noncompliant*squeeze(y_noncompliant_frequency_tones(:,tone_index,:)));  % zero-forcing with jammer mitigation noncompliant
        symbols_estimate_projections = zeros(num_receive_antennas,num_transmit_antennas,num_ofdm_symbols);  % initialize estimates for multiple projectors
        for b=1:num_channel_taps
          symbols_estimate_projections(b,:,:) = pinv(sqrt(num_subcarriers)*(squeeze(projectors(b,:,:))*squeeze(user_channel_frequency_tones(:,:,tone_index)))) * (squeeze(projectors(b,:,:))*squeeze(y_noncompliant_frequency_tones(:,tone_index,:)));  % zero-forcing with multiple nulling projectors
        end
      end

      % -- compute bit outputs by minimum distance decoding
      % Corresponds to Section IV (Mitigation): Decoding.
      symbols_estimate_jammerless_vector = reshape(symbols_estimate_jammerless, [num_ofdm_symbols*num_transmit_antennas,1]);  % vectorize estimated symbols
      [~,indices_hat_vector] = min(abs(symbols_estimate_jammerless_vector*ones(1,length(constellation_symbols))-ones(num_ofdm_symbols*num_transmit_antennas,1)*constellation_symbols).^2,[],2);  % find closest constellation points
      indices_hat_jammerless = reshape(indices_hat_vector, [num_transmit_antennas, num_ofdm_symbols]);  % reshape indices
      bits_hat_jammerless = bit_mappings(indices_hat_jammerless,:);  % map indices to bits

      symbols_estimate_compliant_vector = reshape(symbols_estimate_compliant, [num_ofdm_symbols*num_transmit_antennas,1]);
      [~,indices_hat_vector] = min(abs(symbols_estimate_compliant_vector*ones(1,length(constellation_symbols))-ones(num_ofdm_symbols*num_transmit_antennas,1)*constellation_symbols).^2,[],2);
      indices_hat_compliant = reshape(indices_hat_vector, [num_transmit_antennas, num_ofdm_symbols]);
      bits_hat_compliant = bit_mappings(indices_hat_compliant,:);

      symbols_estimate_noncompliant_vector = reshape(symbols_estimate_noncompliant, [num_ofdm_symbols*num_transmit_antennas,1]);
      [~,indices_hat_vector] = min(abs(symbols_estimate_noncompliant_vector*ones(1,length(constellation_symbols))-ones(num_ofdm_symbols*num_transmit_antennas,1)*constellation_symbols).^2,[],2);
      indices_hat_noncompliant = reshape(indices_hat_vector, [num_transmit_antennas, num_ofdm_symbols]);
      bits_hat_noncompliant = bit_mappings(indices_hat_noncompliant,:);

      bits_hat_projections = zeros(num_channel_taps,num_transmit_antennas*num_ofdm_symbols,bits_per_symbol);
      for b=1:num_channel_taps
        symbols_estimate_vector = reshape(symbols_estimate_projections(b,:,:), [num_ofdm_symbols*num_transmit_antennas,1]);
        [~,indices_hat_vector] = min(abs(symbols_estimate_vector*ones(1,length(constellation_symbols))-ones(num_ofdm_symbols*num_transmit_antennas,1)*constellation_symbols).^2,[],2);
        indices_hat = reshape(indices_hat_vector, [num_transmit_antennas, num_ofdm_symbols]);
        bits_hat_projections(b,:,:) = bit_mappings(indices_hat,:);
      end
      

      %gian marti BER
      % -- compute error metrics by comparing estimated bits to true bits
      % Corresponds to Section IV (Mitigation): BER calculation.
      bit_tensor = squeeze(bits(trial_index,:,:,:,tone_index));  % extract bits for current trial and subcarrier
      bit_tensor = permute(bit_tensor,[3 2 1]);  % reorder dimensions for comparison
      true_bits = bit_tensor(:,:)';  % flatten to 2D matrix
      results.BER_jammerless(snr_index) = results.BER_jammerless(snr_index) + sum(sum(true_bits~=bits_hat_jammerless));  % count bit errors jammerless
      results.BER_compliant(snr_index) = results.BER_compliant(snr_index) + sum(sum(true_bits~=bits_hat_compliant));  % count bit errors jammer compliant
      results.BER_noncompliant(snr_index) = results.BER_noncompliant(snr_index) + sum(sum(true_bits~=bits_hat_noncompliant));  % count bit errors jammer noncompliant
      for b=1:num_channel_taps
        results.BER_projections(b,snr_index) = results.BER_projections(b,snr_index) + sum(sum(true_bits~=squeeze(bits_hat_projections(b,:,:))));  % count bit errors for each nulling dimension
      end

    end

  end % iterate over SNRs

  % -- keep track of simulation time and print estimated remaining time
  if(print_messages_flag)
    if toc>10
      time = toc;
      time_elapsed = time_elapsed + time;
        fprintf('estimated remaining simulation time: %3.0f min.\n', ...
          time_elapsed*(num_trials/trial_index-1)/60);
      tic
    end
  end

end % iterate over MC trials


% normalize results
% Corresponds to Section V (Results): Averaging BER over trials and subcarriers.
results.BER_jammerless = results.BER_jammerless/(num_trials*num_transmit_antennas*num_ofdm_symbols*bits_per_symbol*num_used_subcarriers);
results.BER_compliant = results.BER_compliant/(num_trials*num_transmit_antennas*num_ofdm_symbols*bits_per_symbol*num_used_subcarriers);
results.BER_noncompliant = results.BER_noncompliant/(num_trials*num_transmit_antennas*num_ofdm_symbols*bits_per_symbol*num_used_subcarriers);
results.BER_projections = results.BER_projections/(num_trials*num_transmit_antennas*num_ofdm_symbols*bits_per_symbol*num_used_subcarriers);

results.spatial_interference_distribution = results.spatial_interference_distribution./sum(results.spatial_interference_distribution,1);
results.spatial_interference_distribution_sc_std = std(results.spatial_interference_distribution.');
results.spatial_interference_distribution_sc_avg = mean(results.spatial_interference_distribution.');


%%% plot results
% Corresponds to Section V (Results): Visualization of BER performance and interference distribution.
if plot_flag

  figure(1)
  semilogy(snr_dB_list, results.BER_jammerless, 'LineWidth', 2.5)
  hold on
  semilogy(snr_dB_list, results.BER_compliant, 'LineWidth', 2.5)
  semilogy(snr_dB_list, results.BER_noncompliant, 'LineWidth', 2.5)
  hold off
  grid on
  axis([min(snr_dB_list) max(snr_dB_list) 1e-4 1])
  xlabel('average SNR per receive antenna [dB]','FontSize',12)
  ylabel('uncoded bit error-rate (BER)','FontSize',12)
  legend(["Jammerless", "OFDM-compliant jammer", "Cyclic prefix-violating jammer"],'FontSize',12,'Interpreter','none')
  set(gca,'FontSize',12)
  set(gcf,'position',[10,10,400,300])

  figure(2)
  bar(1:num_receive_antennas,results.spatial_interference_distribution_sc_avg)
  hold on
  er = errorbar(1:num_receive_antennas,results.spatial_interference_distribution_sc_avg, ...
    2*results.spatial_interference_distribution_sc_std, ...
    2*results.spatial_interference_distribution_sc_std);
  er.Color = [0 0 0];
  er.LineStyle = 'none';
  hold off
  axis([0.25 num_receive_antennas+0.75 0 1])
  grid on
  xlabel('ordered spatial dimension index')
  ylabel('fraction of receive interference')
  set(gcf,'position',[10,10,400,300])

  figure(3)
  semilogy(snr_dB_list, results.BER_projections(1,:), 'LineWidth', 2.5)
  hold on
  for b=1:num_channel_taps
    semilogy(snr_dB_list, results.BER_projections(b,:), 'LineWidth', 2.5)
  end
  hold off
  grid on
  axis([min(snr_dB_list) max(snr_dB_list) 1e-4 1])
  xlabel('average SNR per receive antenna [dB]','FontSize',12)
  ylabel('uncoded bit error-rate (BER)','FontSize',12)
  numbers = {};
  for b = 1:num_channel_taps
    numbers{b} = "Cyclic Prefix-Violating Jammer: Null " + num2str(b) + " Dimensions";
  end
  legend(numbers,'FontSize',12,'Interpreter','none')
  set(gca,'FontSize',12)
  set(gcf,'position',[10,10,400,300])


  figure(4)
  plot(1:num_used_subcarriers, abs(squeeze(plot_signal_jammerless(1,:,1))), 'LineWidth', 2, 'Color', 'blue')
  xlabel('Used Subcarrier Index','FontSize',12)
  ylabel('Signal Magnitude','FontSize',12)
  title('Jammerless Signal After Subcarriers Assignment','FontSize',12)
  grid on
  set(gca,'FontSize',12)
  set(gcf,'position',[10,10,400,300])

  figure(5)
  plot(1:num_used_subcarriers, abs(squeeze(plot_signal_compliant(1,:,1))), 'LineWidth', 2, 'Color', 'red')
  xlabel('Used Subcarrier Index','FontSize',12)
  ylabel('Signal Magnitude','FontSize',12)
  title('Jammer Compliant Signal After Subcarriers Assignment','FontSize',12)
  grid on
  set(gca,'FontSize',12)
  set(gcf,'position',[10,10,400,300])

  figure(6)
  plot(1:num_used_subcarriers, abs(squeeze(plot_signal_noncompliant(1,:,1))), 'LineWidth', 2, 'Color', 'yellow')
  xlabel('Used Subcarrier Index','FontSize',12)
  ylabel('Signal Magnitude','FontSize',12)
  title('Jammer NonCompliant Signal After Subcarriers Assignment','FontSize',12)
  grid on
  set(gca,'FontSize',12)
  set(gcf,'position',[10,10,400,300])


  % Plot jammer statistics
  figure(8)
  % Average magnitude over trials and OFDM symbols for compliant jammer
  jammer_compliant_avg = mean(mean(abs(jammer_compliant_freq_domain),4),1); % Average over trials and OFDM symbols
  subplot(2,1,1)
  imagesc(squeeze(jammer_compliant_avg))
  title('Jammer Compliant Frequency Domain Magnitude (Avg over Trials and OFDM Symbols)')
  xlabel('Receive Antenna')
  ylabel('Subcarrier Index')
  colorbar

  % Average magnitude over trials and OFDM symbols for noncompliant jammer
  jammer_noncompliant_avg = mean(mean(abs(jammer_noncompliant_freq_domain),4),1); % Average over trials and OFDM symbols
  subplot(2,1,2)
  imagesc(squeeze(jammer_noncompliant_avg))
  title('Jammer Noncompliant Frequency Domain Magnitude (Avg over Trials and OFDM Symbols)')
  xlabel('Receive Antenna')
  ylabel('Subcarrier Index')
  colorbar
  set(gcf,'position',[10,10,600,400])

end

% Compute and print success rate of estimation and detection
% Success rate: percentage of SNR points where mitigated BER (noncompliant) is lower than unmitigated (compliant)
% Formula derivation:
% - results.BER_compliant and results.BER_noncompliant are vectors (one value per SNR point).
% - results.BER_compliant > results.BER_noncompliant: logical vector (1 if mitigation improved BER, 0 otherwise).
% - mean() gives fraction of SNR points where BER_noncompliant < BER_compliant (successful mitigation).
% - Multiply by 100 for percentage.
success_rate = mean(results.BER_compliant > results.BER_noncompliant) * 100;
fprintf('Success rate of jammer detection and mitigation: %.2f%%\n', success_rate);

% Print average BER for reference
fprintf('Average BER - Jammerless: %.4f\n', mean(results.BER_jammerless));
fprintf('Average BER - Compliant Jammer (unmitigated): %.4f\n', mean(results.BER_compliant));
fprintf('Average BER - Noncompliant Jammer (mitigated): %.4f\n', mean(results.BER_noncompliant));


    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %SO3 PLOT charaterization
            % --- Plotting the receive signals ---
            if mod(t, 5) == 0 % Update plot every 5 trials
                cla(app.UIAxes_2); 
                hold(app.UIAxes_2, 'on'); 
            
                % Define colors for each signal type
                color_jammerless = 'b';       % Blue
                color_compliant = 'g';        % Green
                color_noncompliant = 'r';     % Red
                color_full_compliant = 'c';   % Cyan
                color_full_noncompliant = 'm';% Magenta
            
                % Plot signals on app.UIAxes_2 with specified colors
                plot(app.UIAxes_2, real(y_jl(1, :)), 'Color', color_jammerless, 'DisplayName', 'TWSO LEGITIMATE RECEIVE SIGNAL', 'LineWidth', 1.5);
                plot(app.UIAxes_2, real(y_oj_comp(1, :)), 'Color', color_compliant, 'DisplayName', 'TVWSO COMPLAINT RECEIVE SIGNAL', 'LineWidth', 1.5);
                plot(app.UIAxes_2, real(y_oj_noncomp(1, :)), 'Color', color_noncompliant, 'DisplayName', 'TWSO NON-COMPLAINT RECEIVE SIGNAL', 'LineWidth', 1.5);
                plot(app.UIAxes_2, real(y_comp(1, :)), 'Color', color_full_compliant, 'DisplayName', 'TVWSO FULL COMPLAINT RECEIVE SIGNAL', 'LineWidth', 1.5);
                plot(app.UIAxes_2, real(y_noncomp(1, :)), 'Color', color_full_noncompliant, 'DisplayName', 'TVWSO FULL NON-COMPLAINT RECEIVE SIGNAL', 'LineWidth', 1.5);
            
                % Set plot properties
                title(app.UIAxes_2, ['Receive Signals for First Antenna - Trial ' num2str(t)]);
                xlabel(app.UIAxes_2, 'Sample Index');
                ylabel(app.UIAxes_2, 'Amplitude');
                legend(app.UIAxes_2, 'show');
                grid(app.UIAxes_2, 'on');
                hold(app.UIAxes_2, 'off'); % Release the hold on the axes
                drawnow; % Update the UI figure window
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            end % iterate over MC trials
            
            % normalize results
            %par.trials = 100, par.U = 2, par.num_ofdm_symbols = 50, par.Q = 2, par.num_tones = 48
            %res.BER_jl_R = res.BER_jl/(par.trials*par.U*par.num_ofdm_symbols*par.Q*par.num_tones); %res.BER_jl = (1x26)
            %res.BER_comp_R = res.BER_comp/(par.trials*par.U*par.num_ofdm_symbols*par.Q*par.num_tones); %res.BER_comp = (1x26)
            %res.BER_noncomp_R = res.BER_noncomp/(par.trials*par.U*par.num_ofdm_symbols*par.Q*par.num_tones); %res.BER_noncomp = (1x26)
            %res.BER_projs_R = res.BER_projs/(par.trials*par.U*par.num_ofdm_symbols*par.Q*par.num_tones); %res.BER_projs = (4x26)
            
            %SNRdB_list 
            %res.spatial_interference_distr_R = res.spatial_interference_distr./sum(res.spatial_interference_distr,1); % res.spatial_interference_distr = (8x48)
            %res.spatial_interference_distr_sc_std = std(res.spatial_interference_distr_R.'); %res.spatial_interference_distr_sc_std = 1x8
            %res.spatial_interference_distr_sc_avg = mean(res.spatial_interference_distr_R.'); %res.spatial_interference_distr_sc_avg = 1x8

            %res.BER_dummy = [
            %res.BER_jl_R; 
            %res.BER_projs_R; 
            %];
        %
     end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


    properties (Access = private)
        category %STORAGE PARRA SA CATEGORY
        filename = 'TDRRDB.xlsx'
        geoloc
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
                        
                        % ==========================================================================
            % -- Single-Antenna Jammers in MIMO-OFDM Can Resemble Multi-Antenna Jammers
            % --------------------------------------------------------------------------
            % -- (c) 2023 Gian Marti
            % -- e-mail: gimarti@ethz.ch
            % --------------------------------------------------------------------------
            % -- If you use this simulator or parts of it, then you must cite our paper:
            %
            % -- Gian Marti and Christoph Studer,
            % -- "Single-Antenna Jammers in MIMO-OFDM Can Resemble Multi-Antenna Jammers," 
            % -- IEEE Communication Letters, 2023
            % ==========================================================================
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% configurable parameters
            %%%%%%%%%%%%%%%%%%%%%%%%%%%
            params.num_receive_antennas = 8;                    % number of receive antennas
            params.num_transmit_antennas = 2;                    % number of transmit antennas and data streams
            params.num_channel_taps = 4;                    % number of channel taps
            params.cyclic_prefix_length = 16;                   % cyclic prefix length
            params.num_subcarriers = 64;                % number of total subcarriers
            params.used_subcarrier_indices = [-26:-22, -20:-8, -6:-1, 1:6, 8:20, 22:26] + 32; % list of used subcarriers, only meaningful for params.num_subcarriers = 64;
            params.num_used_subcarriers = length(params.used_subcarrier_indices);
            params.num_ofdm_symbols_per_trial = 50;    % number of OFDM symbols sent per channel realization
            params.jammer_strength_dB = 30;              % jammer strength relative to signal strength (in dB)
            params.modulation_scheme = 'QPSK';             % transmit constellation
            params.random_jammer_power_flag = 0;  % if true, then the jammer-power is uniformly (in dB) sampled from
                                          % the range [0, params.jammer_strength_dB] for every trial.
                                          % Otherwise, it is deterministically equal params.jammer_strength_dB.
            
            params.num_monte_carlo_trials = 100;             % number of Monte-Carlo trials (different channel realizations)
            params.snr_dB_list = -5:1:20;     % list of SNR values (in dB) to be simulated
            
            params.plot_results_flag = 1;                 % whether or not to plot the results
            params.print_messages_flag = 1;             % whether or not to print log messages
            
            rng(0);                       % random seed
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% simulation start
            %%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            params.simulation_name = [num2str(params.num_receive_antennas) 'x' num2str(params.num_transmit_antennas) '_' params.modulation_scheme '_L', num2str(params.num_channel_taps) '_P' num2str(params.cyclic_prefix_length) ...
              '_Nsc' num2str(params.num_subcarriers) '_Nofd' num2str(params.num_ofdm_symbols_per_trial) '_rho' num2str(params.jammer_strength_dB) ...
              '_' num2str(params.num_monte_carlo_trials) 'Trials'];
            
            switch (params.modulation_scheme)
              case 'BPSK'
                params.constellation_symbols = [ -1 1 ];
              case 'QPSK'
                params.constellation_symbols = [ ...
                  -1-1i,-1+1i, ...
                  +1-1i,+1+1i ];
              case '16QAM'
                params.constellation_symbols = [ ...
                  -3-3i,-3-1i,-3+3i,-3+1i, ...
                  -1-3i,-1-1i,-1+3i,-1+1i, ...
                  +3-3i,+3-1i,+3+3i,+3+1i, ...
                  +1-3i,+1-1i,+1+3i,+1+1i ];
              case '64QAM'
                params.constellation_symbols = [ ...
                  -7-7i,-7-5i,-7-1i,-7-3i,-7+7i,-7+5i,-7+1i,-7+3i, ...
                  -5-7i,-5-5i,-5-1i,-5-3i,-5+7i,-5+5i,-5+1i,-5+3i, ...
                  -1-7i,-1-5i,-1-1i,-1-3i,-1+7i,-1+5i,-1+1i,-1+3i, ...
                  -3-7i,-3-5i,-3-1i,-3-3i,-3+7i,-3+5i,-3+1i,-3+3i, ...
                  +7-7i,+7-5i,+7-1i,+7-3i,+7+7i,+7+5i,+7+1i,+7+3i, ...
                  +5-7i,+5-5i,+5-1i,+5-3i,+5+7i,+5+5i,+5+1i,+5+3i, ...
                  +1-7i,+1-5i,+1-1i,+1-3i,+1+7i,+1+5i,+1+1i,+1+3i, ...
                  +3-7i,+3-5i,+3-1i,+3-3i,+3+7i,+3+5i,+3+1i,+3+3i ];
            end
            
            % normalize transmission constellation
            params.constellation_energy = mean(abs(params.constellation_symbols).^2);
            params.constellation_symbols = params.constellation_symbols/sqrt(params.constellation_energy);
            params.symbol_energy = mean(abs(params.constellation_symbols).^2); % equal to 1
            
            % precompute bit labels
            params.bits_per_symbol = log2(length(params.constellation_symbols)); % number of bits per symbol
            params.bit_labels = de2bi(0:length(params.constellation_symbols)-1,params.bits_per_symbol,'left-msb');
            
            % track simulation time
            time_elapsed = 0;
            
            % intermediate results
            rx_signal_freq_jammerless = zeros(params.num_receive_antennas,params.num_subcarriers,params.num_ofdm_symbols_per_trial);
            rx_jammer_only_freq_compliant = zeros(params.num_receive_antennas,params.num_subcarriers,params.num_ofdm_symbols_per_trial);
            rx_jammer_only_freq_noncompliant = zeros(params.num_receive_antennas,params.num_subcarriers,params.num_ofdm_symbols_per_trial);
            rx_signal_with_jammer_freq_compliant = zeros(params.num_receive_antennas,params.num_subcarriers,params.num_ofdm_symbols_per_trial);
            rx_signal_with_jammer_freq_noncompliant = zeros(params.num_receive_antennas,params.num_subcarriers,params.num_ofdm_symbols_per_trial);
            
            % results
            jammer_signals_freq_compliant_trials = zeros(params.num_monte_carlo_trials, params.num_used_subcarriers,params.num_receive_antennas, params.num_ofdm_symbols_per_trial);
            jammer_signals_freq_noncompliant_trials = zeros(params.num_monte_carlo_trials, params.num_used_subcarriers,params.num_receive_antennas, params.num_ofdm_symbols_per_trial);
            
            MSE_jl = zeros(1, length(params.snr_dB_list));
            MSE_comp = zeros(1, length(params.snr_dB_list));
            MSE_noncomp = zeros(1, length(params.snr_dB_list));
            
            res.BER_jl = zeros(1, length(params.snr_dB_list));
            res.BER_comp = zeros(1, length(params.snr_dB_list));
            res.BER_noncomp = zeros(1, length(params.snr_dB_list));
            res.BER_projs = zeros(params.num_channel_taps, length(params.snr_dB_list));
            
            res.spatial_interference_distr = zeros(params.num_receive_antennas,params.num_used_subcarriers);
            
            % generate bit stream
            transmitted_bits = randi([0 1],params.num_monte_carlo_trials,params.num_ofdm_symbols_per_trial,params.num_transmit_antennas,params.bits_per_symbol,params.num_used_subcarriers);
            symbol_indices = zeros(params.num_monte_carlo_trials, params.num_ofdm_symbols_per_trial, params.num_transmit_antennas,params.num_used_subcarriers);
            
            
            % trials loop
            tic
            for trial_index=1:params.num_monte_carlo_trials
            
              % generate jammer's channel in the time domain
              jammer_channel_time = sqrt(0.5)*randn(params.num_receive_antennas,params.num_channel_taps) + 1j*sqrt(0.5)*randn(params.num_receive_antennas,params.num_channel_taps);
              jammer_channel_freq = (1/sqrt(params.num_subcarriers))*fft(jammer_channel_time,params.num_subcarriers,2);
            
            
              % generate the UEs' channel in the time domain
              user_channel_time = sqrt(0.5)*randn(params.num_receive_antennas,params.num_transmit_antennas,params.num_channel_taps) + 1j*sqrt(0.5)*randn(params.num_receive_antennas,params.num_transmit_antennas,params.num_channel_taps);
              user_channel_freq = (1/sqrt(params.num_subcarriers))*fft(user_channel_time,params.num_subcarriers,3); % E[||H_f(:,:,i)||_F^2] = B*U*L/N_sc for all i=1,...,N_sc
              % so the expected receive signal energy per subcarrier is E_s*B*U*L/N_sc = B*U*L/N_sc
            
              if params.random_jammer_power_flag
                jammer_power = params.num_transmit_antennas*10^(params.jammer_strength_dB*rand()/10);
              else
                jammer_power = params.num_transmit_antennas*10^(params.jammer_strength_dB/10);
              end
            
              for snr_index = 1:length(params.snr_dB_list)
            
                noise_power = params.num_channel_taps*params.num_transmit_antennas*10^(-params.snr_dB_list(snr_index)/10);
            
                for symbol_index=1:params.num_ofdm_symbols_per_trial
            
                  %%% generate transmit signals
            
                  % generate jammer's transmit signal in the frequency domain for OFDM-compliant case
                  jammer_signal_freq_compliant = sqrt(0.5)*randn(1,params.num_subcarriers)+ 1j*sqrt(0.5)*randn(1,params.num_subcarriers);
                  jammer_signal_time_no_cp = sqrt(params.num_subcarriers)*ifft(jammer_signal_freq_compliant); %this does in fact not change the Gaussian statistics
                  jammer_signal_time_compliant = [jammer_signal_time_no_cp(:,params.num_subcarriers-params.cyclic_prefix_length+1:params.num_subcarriers), jammer_signal_time_no_cp];
                  jammer_signal_time_compliant = sqrt(jammer_power)*jammer_signal_time_compliant;
            
                  % generate the jammer's transmit signal in the time domain for OFDM-noncompliant case
                  jammer_signal_time_noncompliant = sqrt(0.5)*randn(1,params.num_subcarriers+params.cyclic_prefix_length)+ 1j*sqrt(0.5)*randn(1,params.num_subcarriers+params.cyclic_prefix_length);
                  jammer_signal_time_noncompliant = sqrt(jammer_power)*jammer_signal_time_noncompliant;
            
                  % generate UEs' transmit signal in the frequency domain for OFDM-compliant case
                  for subcarrier_index=1:params.num_used_subcarriers
                    symbol_indices(trial_index,symbol_index,:,subcarrier_index) = bi2de(transmitted_bits(trial_index,symbol_index,:,:,subcarrier_index),'left-msb')+1;
                  end
                  user_symbols_freq_used = params.constellation_symbols(squeeze(symbol_indices(trial_index,symbol_index,:,:))); % symbol vectors
                  user_signal_freq = zeros(params.num_transmit_antennas, params.num_subcarriers);
                  user_signal_freq(:,params.used_subcarrier_indices) = user_symbols_freq_used; % only transmit signal over the used subcarriers
                  user_signal_time_no_cp = sqrt(params.num_subcarriers)*ifft(user_signal_freq,params.num_subcarriers,2);
                  user_signal_time = [user_signal_time_no_cp(:,params.num_subcarriers-params.cyclic_prefix_length+1:params.num_subcarriers), user_signal_time_no_cp];
            
                
                  %%% generate receive signals
                  rx_signal_time_jammerless = zeros(params.num_receive_antennas, params.num_subcarriers+params.cyclic_prefix_length+params.num_channel_taps-1);          % jammerless receive signal
                  rx_jammer_only_time_compliant = zeros(params.num_receive_antennas, params.num_subcarriers+params.cyclic_prefix_length+params.num_channel_taps-1);     % only compliant jammer
                  rx_jammer_only_time_noncompliant = zeros(params.num_receive_antennas, params.num_subcarriers+params.cyclic_prefix_length+params.num_channel_taps-1);  % only noncompliant jammer
                  rx_signal_with_jammer_time_compliant = zeros(params.num_receive_antennas, params.num_subcarriers+params.cyclic_prefix_length+params.num_channel_taps-1);        % full receive signal, jammer compliant
                  rx_signal_with_jammer_time_noncompliant = zeros(params.num_receive_antennas, params.num_subcarriers+params.cyclic_prefix_length+params.num_channel_taps-1);     % full receive signal, jammer noncompliant
            
                  for antenna_index=1:params.num_receive_antennas
                    for transmit_antenna_index=1:params.num_transmit_antennas
                      rx_signal_time_jammerless(antenna_index,:) = rx_signal_time_jammerless(antenna_index,:) + conv(squeeze(user_channel_time(antenna_index,transmit_antenna_index,:)),user_signal_time(transmit_antenna_index,:));
                    end
                    rx_jammer_only_time_compliant(antenna_index,:) = conv(jammer_channel_time(antenna_index,:),jammer_signal_time_compliant);
                    rx_jammer_only_time_noncompliant(antenna_index,:) = conv(jammer_channel_time(antenna_index,:),jammer_signal_time_noncompliant);
                  end
                  rx_signal_with_jammer_time_compliant = rx_signal_time_jammerless + rx_jammer_only_time_compliant;
                  rx_signal_with_jammer_time_noncompliant = rx_signal_time_jammerless + rx_jammer_only_time_noncompliant;
            
            
                  %%% generate noise in the time domain
                  noise_signal = sqrt(0.5)*randn(params.num_receive_antennas,params.num_subcarriers)+ 1j*sqrt(0.5)*randn(params.num_receive_antennas,params.num_subcarriers);
                  noise_signal = sqrt(noise_power)*noise_signal;
            
                  %%% truncate signals in time-domain to remove cylic prefix and channel reverberation
                  rx_signal_truncated_jammerless = rx_signal_time_jammerless(:,params.cyclic_prefix_length+1:params.cyclic_prefix_length+params.num_subcarriers) + noise_signal;
                  rx_jammer_only_truncated_compliant = rx_jammer_only_time_compliant(:,params.cyclic_prefix_length+1:params.cyclic_prefix_length+params.num_subcarriers); % no noise
                  rx_jammer_only_truncated_noncompliant = rx_jammer_only_time_noncompliant(:,params.cyclic_prefix_length+1:params.cyclic_prefix_length+params.num_subcarriers); % no noise
                  rx_signal_with_jammer_truncated_compliant = rx_signal_with_jammer_time_compliant(:,params.cyclic_prefix_length+1:params.cyclic_prefix_length+params.num_subcarriers) + noise_signal;
                  rx_signal_with_jammer_truncated_noncompliant = rx_signal_with_jammer_time_noncompliant(:,params.cyclic_prefix_length+1:params.cyclic_prefix_length+params.num_subcarriers) + noise_signal;
            
                  %%% convert to frequency domain
                  rx_signal_freq_jammerless(:,:,symbol_index) = (1/sqrt(params.num_subcarriers))*fft(rx_signal_truncated_jammerless, params.num_subcarriers, 2);
                  rx_jammer_only_freq_compliant(:,:,symbol_index) = (1/sqrt(params.num_subcarriers))*fft(rx_jammer_only_truncated_compliant, params.num_subcarriers, 2);
                  rx_jammer_only_freq_noncompliant(:,:,symbol_index) = (1/sqrt(params.num_subcarriers))*fft(rx_jammer_only_truncated_noncompliant, params.num_subcarriers, 2);
                  rx_signal_with_jammer_freq_compliant(:,:,symbol_index) = (1/sqrt(params.num_subcarriers))*fft(rx_signal_with_jammer_truncated_compliant, params.num_subcarriers, 2);
                  rx_signal_with_jammer_freq_noncompliant(:,:,symbol_index) = (1/sqrt(params.num_subcarriers))*fft(rx_signal_with_jammer_truncated_noncompliant, params.num_subcarriers, 2);
            
            
                  %%% remove subcarriers that are not used
                  rx_signal_freq_tones_jammerless = rx_signal_freq_jammerless(:,params.used_subcarrier_indices,:);
                  rx_jammer_only_freq_tones_compliant = rx_jammer_only_freq_compliant(:,params.used_subcarrier_indices,:);
                  rx_jammer_only_freq_tones_noncompliant = rx_jammer_only_freq_noncompliant(:,params.used_subcarrier_indices,:);
                  rx_signal_with_jammer_freq_tones_compliant = rx_signal_with_jammer_freq_compliant(:,params.used_subcarrier_indices,:);
                  rx_signal_with_jammer_freq_tones_noncompliant = rx_signal_with_jammer_freq_noncompliant(:,params.used_subcarrier_indices,:);
            
                  user_channel_freq_tones = user_channel_freq(:,:,params.used_subcarrier_indices);
            
            
                  %%% collect jammer statistics for later analysis
                  for subcarrier_index=1:params.num_used_subcarriers
                    jammer_signals_freq_compliant_trials(trial_index,subcarrier_index,:,symbol_index) = rx_jammer_only_freq_tones_compliant(:,subcarrier_index,symbol_index);
                    jammer_signals_freq_noncompliant_trials(trial_index,subcarrier_index,:,symbol_index) = rx_jammer_only_freq_tones_noncompliant(:,subcarrier_index,symbol_index);
                  end
            
              
                end % iterate over OFDM symbols with same channel realization
            
            
                for subcarrier_index=1:params.num_used_subcarriers
            
                  %%% extract jammer statistics
                  [U_comp,S_comp,~] = svd(squeeze(jammer_signals_freq_compliant_trials(trial_index,subcarrier_index,:,:)));
                  [U_noncomp,S_noncomp,~] = svd(squeeze(jammer_signals_freq_noncompliant_trials(trial_index,subcarrier_index,:,:)));
                  res.spatial_interference_distr(:,subcarrier_index) = res.spatial_interference_distr(:,subcarrier_index) + diag(S_noncomp); % interference distribution over different spatial dimensions


                  % form best rank-(B-1) projectors: those that remove most of the
                  % jammer interference per subcarrier
                  jammer_est_comp = U_comp(:,1);
                  projector_comp = eye(params.num_receive_antennas) - jammer_est_comp*jammer_est_comp';
                  jammer_est_noncomp = U_noncomp(:,1);
                  projector_noncomp = eye(params.num_receive_antennas) - jammer_est_noncomp*jammer_est_noncomp';
            
                  projectors = zeros(params.num_channel_taps,params.num_receive_antennas,params.num_receive_antennas);
                  for tap_index=1:params.num_channel_taps
                    projectors(tap_index,:,:) = eye(params.num_receive_antennas) - U_noncomp(:,1:tap_index)*U_noncomp(:,1:tap_index)';
                  end
            
                  %%% detect signals using zero-forcing (and, in some cases, jammer mitigation)
                  symbol_est_jl = (sqrt(params.num_subcarriers)*(user_channel_freq_tones(:,:,subcarrier_index)))\squeeze(rx_signal_freq_tones_jammerless(:,subcarrier_index,:));
                  symbol_est_comp = (sqrt(params.num_subcarriers)*(projector_comp*squeeze(user_channel_freq_tones(:,:,subcarrier_index))))\(projector_comp*squeeze(rx_signal_with_jammer_freq_tones_compliant(:,subcarrier_index,:)));
                  symbol_est_noncomp = (sqrt(params.num_subcarriers)*(projector_noncomp*squeeze(user_channel_freq_tones(:,:,subcarrier_index))))\(projector_noncomp*squeeze(rx_signal_with_jammer_freq_tones_noncompliant(:,subcarrier_index,:)));
                  symbol_est_projs = zeros(params.num_receive_antennas,params.num_transmit_antennas,params.num_ofdm_symbols_per_trial);
                  for tap_index=1:params.num_channel_taps
                    symbol_est_projs(tap_index,:,:) = (sqrt(params.num_subcarriers)*(squeeze(projectors(tap_index,:,:))*squeeze(user_channel_freq_tones(:,:,subcarrier_index))))\(squeeze(projectors(tap_index,:,:))*squeeze(rx_signal_with_jammer_freq_tones_noncompliant(:,subcarrier_index,:)));
                  end
            
                  % -- compute bit outputs
                  symbol_est_jl_vec = reshape(symbol_est_jl, [params.num_ofdm_symbols_per_trial*params.num_transmit_antennas,1]);
                  [~,idxhat_vec] = min(abs(symbol_est_jl_vec*ones(1,length(params.constellation_symbols))-ones(params.num_ofdm_symbols_per_trial*params.num_transmit_antennas,1)*params.constellation_symbols).^2,[],2);
                  idxhat_jl = reshape(idxhat_vec, [params.num_transmit_antennas, params.num_ofdm_symbols_per_trial]);
                  bithat_jl = params.bit_labels(idxhat_jl,:);
            
                  symbol_est_comp_vec = reshape(symbol_est_comp, [params.num_ofdm_symbols_per_trial*params.num_transmit_antennas,1]);
                  [~,idxhat_vec] = min(abs(symbol_est_comp_vec*ones(1,length(params.constellation_symbols))-ones(params.num_ofdm_symbols_per_trial*params.num_transmit_antennas,1)*params.constellation_symbols).^2,[],2);
                  idxhat_comp = reshape(idxhat_vec, [params.num_transmit_antennas, params.num_ofdm_symbols_per_trial]);
                  bithat_comp = params.bit_labels(idxhat_comp,:);
            
                  symbol_est_noncomp_vec = reshape(symbol_est_noncomp, [params.num_ofdm_symbols_per_trial*params.num_transmit_antennas,1]);
                  [~,idxhat_vec] = min(abs(symbol_est_noncomp_vec*ones(1,length(params.constellation_symbols))-ones(params.num_ofdm_symbols_per_trial*params.num_transmit_antennas,1)*params.constellation_symbols).^2,[],2);
                  idxhat_noncomp = reshape(idxhat_vec, [params.num_transmit_antennas, params.num_ofdm_symbols_per_trial]);
                  bithat_noncomp = params.bit_labels(idxhat_noncomp,:);
            
                  bithat_projs = zeros(params.num_channel_taps,params.num_transmit_antennas*params.num_ofdm_symbols_per_trial,params.bits_per_symbol);
                  for tap_index=1:params.num_channel_taps
                    symbol_est_vec = reshape(symbol_est_projs(tap_index,:,:), [params.num_ofdm_symbols_per_trial*params.num_transmit_antennas,1]);
                    [~,idxhat_vec] = min(abs(symbol_est_vec*ones(1,length(params.constellation_symbols))-ones(params.num_ofdm_symbols_per_trial*params.num_transmit_antennas,1)*params.constellation_symbols).^2,[],2);
                    idxhat = reshape(idxhat_vec, [params.num_transmit_antennas, params.num_ofdm_symbols_per_trial]);
                    bithat_projs(tap_index,:,:) = params.bit_labels(idxhat,:);
                  end
            
                  % -- compute error metrics
                  bit_tensor = squeeze(transmitted_bits(trial_index,:,:,:,subcarrier_index));
                  bit_tensor = permute(bit_tensor,[3 2 1]);
                  true_bits = bit_tensor(:,:)';
                  res.BER_jl(snr_index) = res.BER_jl(snr_index) + sum(sum(true_bits~=bithat_jl));
                  res.BER_comp(snr_index) = res.BER_comp(snr_index) + sum(sum(true_bits~=bithat_comp));
                  res.BER_noncomp(snr_index) = res.BER_noncomp(snr_index) + sum(sum(true_bits~=bithat_noncomp));
                  for tap_index=1:params.num_channel_taps
                    res.BER_projs(tap_index,snr_index) = res.BER_projs(tap_index,snr_index) + sum(sum(true_bits~=squeeze(bithat_projs(tap_index,:,:))));
                  end
            
                end
            
            
              end % iterate over SNRs
            
              % -- keep track of simulation time
              if(params.print_messages_flag)
                if toc>10
                  time = toc;
                  time_elapsed = time_elapsed + time;
                    fprintf('estimated remaining simulation time: %3.0f min.\n', ...
                      time_elapsed*(params.num_monte_carlo_trials/trial_index-1)/60);
                  tic
                end
              end
            
            end % iterate over MC trials
            
            % normalize results
            res.BER_jl = res.BER_jl/(params.num_monte_carlo_trials*params.num_transmit_antennas*params.num_ofdm_symbols_per_trial*params.bits_per_symbol*params.num_used_subcarriers);
            res.BER_comp = res.BER_comp/(params.num_monte_carlo_trials*params.num_transmit_antennas*params.num_ofdm_symbols_per_trial*params.bits_per_symbol*params.num_used_subcarriers);
            res.BER_noncomp = res.BER_noncomp/(params.num_monte_carlo_trials*params.num_transmit_antennas*params.num_ofdm_symbols_per_trial*params.bits_per_symbol*params.num_used_subcarriers);
            res.BER_projs = res.BER_projs/(params.num_monte_carlo_trials*params.num_transmit_antennas*params.num_ofdm_symbols_per_trial*params.bits_per_symbol*params.num_used_subcarriers);
            
            res.spatial_interference_distr = res.spatial_interference_distr./sum(res.spatial_interference_distr,1);
            res.spatial_interference_distr_sc_std = std(res.spatial_interference_distr.');
            res.spatial_interference_distr_sc_avg = mean(res.spatial_interference_distr.');


            %%% plot results
            if params.plot_results_flag
            
              figure(1)
              semilogy(params.snr_dB_list, res.BER_jl, 'LineWidth', 2.5)
              hold on
              semilogy(params.snr_dB_list, res.BER_comp, 'LineWidth', 2.5)
              semilogy(params.snr_dB_list, res.BER_noncomp, 'LineWidth', 2.5)
              hold off
              grid on
              axis([min(params.snr_dB_list) max(params.snr_dB_list) 1e-4 1])  
              xlabel('average SNR per receive antenna [dB]','FontSize',12)
              ylabel('uncoded bit error-rate (BER)','FontSize',12)
              legend(["Jammerless", "OFDM-compliant jammer", "Cyclic prefix-violating jammer"],'FontSize',12,'Interpreter','none')
              set(gca,'FontSize',12)
              set(gcf,'position',[10,10,400,300])
            
              figure(2)
              bar(1:params.num_receive_antennas,res.spatial_interference_distr_sc_avg)
              hold on
              er = errorbar(1:params.num_receive_antennas,res.spatial_interference_distr_sc_avg, ...
                2*res.spatial_interference_distr_sc_std, ...
                2*res.spatial_interference_distr_sc_std);
              er.Color = [0 0 0];
              er.LineStyle = 'none';
              hold off
              axis([0.25 params.num_receive_antennas+0.75 0 1])
              grid on
              xlabel('ordered spatial dimension index')
              ylabel('fraction of receive interference')
              set(gcf,'position',[10,10,400,300])
            
              figure(3)
              semilogy(params.snr_dB_list, res.BER_projs(1,:), 'LineWidth', 2.5)
              hold on
              for b=2:params.num_channel_taps
                semilogy(params.snr_dB_list, res.BER_projs(b,:), 'LineWidth', 2.5)
              end
              hold off
              grid on
              axis([min(params.snr_dB_list) max(params.snr_dB_list) 1e-4 1])
              xlabel('average SNR per receive antenna [dB]','FontSize',12)
              ylabel('uncoded bit error-rate (BER)','FontSize',12)
              numbers = {};
              for b = 1:params.num_channel_taps
                numbers{b} = "Null " + num2str(b) + " Dimensions";
              end
              legend(numbers,'FontSize',12,'Interpreter','none')
              set(gca,'FontSize',12)
              set(gcf,'position',[10,10,400,300])
            
            end


            AccessButtonStart_runmontecarlo_btn(app, event)

            % Prevent restarting if already running
            if app.simulationActive
                uialert(app.UIFigure, 'Simulation is already running.', 'Notice');
                return;
            end
        
            % Initialize simulation
            app.simulationActive = true;
            app.situationIndex = 0;
            app.readyForNext = true;
            app.allDone = false;
        
            % Randomize 4 reds (0) and 1 green (1)
            results = [zeros(1,4), 1];
            app.situationResults = results(randperm(5));

            app.Lamp.Color = [1 1 1];
             % Lamp orange — ready to start
           
        end

        % Button pushed function: TVWSOperatorButton
        function TVWSOperatorButtonPushed(app, event)


    % Make sure simulation is started
    if ~app.simulationActive
        msgbox('Please start the simulation first.', 'Notice', 'warn');
        return;
    end

    % --- Initialize randomized situations only once per run ---
    if isempty(app.randomOrder)
        app.situations = {
            'OFDM-compliant Jammer', 'Illegal';
            'Cyclic prefix-violator', 'Illegal';
            'Cyclic prefix-violator', 'Illegal';
            'Cyclic prefix-violator', 'Illegal';
            'None', 'Legitimate'
        };

        app.randomOrder = randperm(5);  % randomize order 1–5
        app.situationIndex = 0;         % start index
    end

    % --- Increment to next situation ---
    app.situationIndex = app.situationIndex + 1;

    % --- Stop if all 5 done ---
    if app.situationIndex > 5
        msgbox('You have run all 5 situations. Please click Reset Simulation to start again.', ...
               'Simulation Complete');
        return;
    end

    % --- Get current situation (randomized) ---
    idx = app.randomOrder(app.situationIndex);
    chosenValue = app.situations{idx, 1};
    statusText = app.situations{idx, 2};

    % --- Update Run Time label ---
    app.RunTimeLabel.Text = sprintf('Run Time: %d', app.situationIndex);

    % --- Reset all visible lamps or outputs ---
    elements = {'otpr1','otpr2','otpr3','otpr4','otpr5','otpr6','otpr7','otpr8','error1','error2','success'};
    for i = 1:numel(elements)
        if isprop(app, elements{i})
            app.(elements{i}).Visible = 'off';
        end
    end
    pause(0.3);

    % --- Simulate operator detection ---
    app.Lamp.Color = [1 0.65 0]; % Orange for estimating
    pause(1);

    if strcmp(statusText, 'Illegal')
        % 🔴 Illegal behavior
        app.otpr1.Visible = 'on'; pause(0.5);
        app.otpr2.Visible = 'on'; pause(0.5);
        app.otpr3.Visible = 'on'; pause(0.5);
        app.error1.Visible = 'on';
        app.Lamp.Color = [1 0 0];
        msgbox(sprintf('Threat Detected: %s', chosenValue), 'Notice', 'warn');
    else
        % 🟢 Legitimate operator
        app.otpr1.Visible = 'on';
        app.otpr2.Visible = 'on';
        app.otpr3.Visible = 'on'; pause(1);
        app.otpr4.Visible = 'on'; 
        app.otpr5.Visible = 'on'; 
        app.otpr6.Visible = 'on'; pause(1);
        app.otpr7.Visible = 'on'; 
        app.success.Visible = 'on';
        app.Lamp.Color = [0 1 0];
    end

    % --- Log Data ---
    currentDate = datestr(now, 'mmmm dd, yyyy');
    currentTime = datestr(now, 'HH:MM:SS');
    operatorId = num2str(app.situationIndex);

    % --- Add row to UITable ---
    row = {operatorId, currentDate, currentTime, statusText};
    app.UITable.Data = [app.UITable.Data; row];

    % --- Update corresponding EditField (1–5) ---
    fieldName = sprintf('EditField_%d', app.situationIndex);
    if isprop(app, fieldName)
        app.(fieldName).Value = sprintf('%s', chosenValue);
    end

    % --- Apply row style ---
    try
        greenStyle = uistyle('BackgroundColor', [0.8 1 0.8]);
        redStyle   = uistyle('BackgroundColor', [1 0.8 0.8]);

        if strcmp(statusText, 'Legitimate')
            addStyle(app.UITable, greenStyle, 'row', size(app.UITable.Data, 1));
        else
            addStyle(app.UITable, redStyle, 'row', size(app.UITable.Data, 1));
        end
    catch
        warning('Could not apply row color styling.');
    end

        end

        % Button pushed function: ResetTVWSOpeatorButton
        function ResetTVWSOpeatorButtonPushed(app, event)
            % Must start first
            if ~app.simulationActive
                uialert(app.UIFigure, 'Please start the simulation first.', 'Notice');
                return;
            end

            app.success.Visible = 'off';  
            app.otpr7.Visible = 'off'; 
            app.otpr6.Visible = 'off'; pause(1);
            app.otpr5.Visible = 'off'; 
            app.otpr4.Visible = 'off';
            app.error1.Visible = 'off'; 
            app.error2.Visible = 'off'; 
            app.otpr3.Visible = 'off'; pause(1);
            app.otpr2.Visible = 'off'; 
            app.otpr1.Visible = 'off';
        
            % --- CASE 1: If all 5 done (recheck mode) ---
            if app.allDone
                % Go to next situation automatically (no orange)
                app.situationIndex = app.situationIndex + 1;
        
                % If reach beyond 5, restart recheck loop
                if app.situationIndex > 5
                    app.situationIndex = 1;
                end
        
                % Show same results directly
                if app.situationResults(app.situationIndex) == 1
                    app.Lamp.Color = [0 1 0]; % Green
                else
                    app.Lamp.Color = [1 0 0]; % Red
                end
        
                % Keep ready for next recheck
                app.readyForNext = true;
                return;
            end
        
            % --- CASE 2: Normal simulation (first loop) ---
            app.readyForNext = true;
            app.Lamp.Color = [1 0.65 0]; % Orange
        end

        % Button pushed function: ResetSimulationButton
        function ResetSimulationButtonPushed(app, event)

    % Reset simulation state
    app.situationIndex = 0;
    app.randomOrder = [];
    app.UITable.Data = {};
    app.RunTimeLabel.Text = 'Run Time: 0';
    app.Lamp.Color = [0.5 0.5 0.5]; % gray
    app.success.Visible = 'off';
    app.error1.Visible = 'off';
    app.error2.Visible = 'off';

    % Clear all edit fields
    for i = 1:5
        fieldName = sprintf('EditField_%d', i);
        if isprop(app, fieldName)
            app.(fieldName).Value = '';
        end
    end

    msgbox('Simulation has been reset. You can now start again.', 'Reset Complete');


        end

        % Button down function: UIAxes_2
        function AccessButtonStart_runmontecarlo_btn(app, event)
          
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.Panel_4 = uipanel(app.SimulationTab);
            app.GridLayout = uigridlayout(app.Panel_4);
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_3 = uipanel(app.GridLayout);
            app.Panel_3.Title = '.';
            app.GridLayout3 = uigridlayout(app.Panel_3);
            app.TVWSOperatorButton = uibutton(app.GridLayout3, 'push');
            app.TVWSOperatorButton.ButtonPushedFcn = createCallbackFcn(app, @TVWSOperatorButtonPushed, true);
            app.TVWSOperatorButton.Text = 'TVWS Operator';
            app.Lamp = uilamp(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.EstimatingLabel = uilabel(app.GridLayout4);
            app.EstimatingLabel.Text = 'Estimating';
            app.IllegalAccessLabel = uilabel(app.GridLayout4);
            app.IllegalAccessLabel.Text = 'Illegal Access';
            app.LegitimateAccessLabel = uilabel(app.GridLayout4);
            app.LegitimateAccessLabel.Text = 'Legitimate Access';
            app.Lamp_2 = uilamp(app.GridLayout4);
            app.Lamp_3 = uilamp(app.GridLayout4);
            app.Lamp_4 = uilamp(app.GridLayout4);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.StartSimulationButton = uibutton(app.GridLayout2, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.ResetSimulationButton = uibutton(app.GridLayout2, 'push');
            app.ResetSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSimulationButtonPushed, true);
            app.ResetSimulationButton.Text = 'Reset Simulation';
            app.RunTimeLabel = uilabel(app.GridLayout2);
            app.RunTimeLabel.Text = 'Run Time: 0';
            app.Panel_5 = uipanel(app.SimulationTab);
            app.SimulationControlsLabel = uilabel(app.Panel_5);
            app.SimulationControlsLabel.Text = 'Simulation Controls';
            app.AccessBTSLabel = uilabel(app.SimulationTab);
            app.AccessBTSLabel.Text = 'Access BTS';
            app.Panel_6 = uipanel(app.SimulationTab);
            app.ThreatDetectionPanelLabel = uilabel(app.Panel_6);
            app.ThreatDetectionPanelLabel.Text = 'Threat Detection Panel';
            app.Panel_7 = uipanel(app.SimulationTab);
            app.GridLayout5 = uigridlayout(app.Panel_7);
            app.EditField_1 = uieditfield(app.GridLayout5, 'text');
            app.EditField_2 = uieditfield(app.GridLayout5, 'text');
            app.EditField_3 = uieditfield(app.GridLayout5, 'text');
            app.EditField_4 = uieditfield(app.GridLayout5, 'text');
            app.EditField_5 = uieditfield(app.GridLayout5, 'text');
            app.Panel_10 = uipanel(app.SimulationTab);
            app.UIAxes_2 = uiaxes(app.Panel_10);
            title(app.UIAxes_2, 'Figure 1')
            xlabel(app.UIAxes_2, 'avg. SNR per receive antenna [dB]')
            ylabel(app.UIAxes_2, 'uncoded bit error-rate (BER)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @AccessButtonStart_runmontecarlo_btn, true);
            app.Panel_9 = uipanel(app.SimulationTab);
            app.Image_2 = uiimage(app.Panel_9);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Images', 'towertowertower.png');
            app.Image = uiimage(app.Panel_9);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'bts.png');
            app.otpr5 = uibutton(app.Panel_9, 'push');
            app.otpr5.Visible = 'off';
            app.otpr7 = uibutton(app.Panel_9, 'push');
            app.otpr7.Visible = 'off';
            app.otpr8 = uibutton(app.Panel_9, 'push');
            app.otpr8.Visible = 'off';
            app.otpr4 = uibutton(app.Panel_9, 'push');
            app.otpr4.Visible = 'off';
            app.otpr3 = uibutton(app.Panel_9, 'push');
            app.otpr3.Visible = 'off';
            app.otpr2 = uibutton(app.Panel_9, 'push');
            app.otpr2.Visible = 'off';
            app.otpr1 = uibutton(app.Panel_9, 'push');
            app.otpr1.Visible = 'off';
            app.error2 = uiimage(app.Panel_9);
            app.error2.Visible = 'off';
            app.error2.ImageSource = fullfile(pathToMLAPP, 'Images', 'remove-removebg-preview.png');
            app.error1 = uiimage(app.Panel_9);
            app.error1.Visible = 'off';
            app.error1.ImageSource = fullfile(pathToMLAPP, 'Images', 'remove-removebg-preview.png');
            app.otpr6 = uibutton(app.Panel_9, 'push');
            app.otpr6.Visible = 'off';
            app.success = uiimage(app.Panel_9);
            app.success.Visible = 'off';
            app.success.ImageSource = fullfile(pathToMLAPP, 'Images', 'check.png');
            app.BaseTranscieverStationLabel = uilabel(app.Panel_9);
            app.BaseTranscieverStationLabel.Text = 'Base Transciever Station';
            app.TVWSOperatorLabel = uilabel(app.Panel_9);
            app.TVWSOperatorLabel.Text = 'TVWS Operator';
            app.Panel_11 = uipanel(app.SimulationTab);
            app.UIAxes = uiaxes(app.Panel_11);
            title(app.UIAxes, {'Recieve Signal '; 'Characterization'})
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.SimulationTab);
            app.UITable.ColumnName = {'#'; 'Date'; 'Time'; 'Status'};
            app.UITable.RowName = {};
            app.Panel_12 = uipanel(app.SimulationTab);
            app.ThreatMitigationPanelLabel = uilabel(app.Panel_12);
            app.ThreatMitigationPanelLabel.Text = 'Threat Mitigation Panel';
            app.LogsTab = uitab(app.TabGroup);
            app.LogsTab.Title = 'Logs';
            app.UIAxes3 = uiaxes(app.LogsTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.LogsTab);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_3 = uiaxes(app.LogsTab);
            title(app.UIAxes3_3, 'Title')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes3_4 = uiaxes(app.LogsTab);
            title(app.UIAxes3_4, 'Title')
            xlabel(app.UIAxes3_4, 'X')
            ylabel(app.UIAxes3_4, 'Y')
            zlabel(app.UIAxes3_4, 'Z')
            app.UITable1 = uitable(app.LogsTab);
            app.UITable1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable1.RowName = {};
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '.';
            app.ResetTVWSOpeatorButton = uibutton(app.Tab, 'push');
            app.ResetTVWSOpeatorButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTVWSOpeatorButtonPushed, true);
            app.ResetTVWSOpeatorButton.Text = 'Reset TVWS Opeator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tiffverSISO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end